classdef smoothing_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        AutomaticButton            matlab.ui.control.Button
        StatesDropDown             matlab.ui.control.DropDown
        StatesDropDownLabel        matlab.ui.control.Label
        CellsSlider                matlab.ui.control.Slider
        CellstodisplaySliderLabel  matlab.ui.control.Label
        FinishButton               matlab.ui.control.Button
        KnotsSlider                matlab.ui.control.Slider
        OverridewithequidistantknotsSliderLabel  matlab.ui.control.Label
        LiveCheckBox               matlab.ui.control.CheckBox
        SmoothButton               matlab.ui.control.Button
        KnotsSelectorUIAxes        matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        smoother
        state_names
        tl
        lastplots
        N
        L
        active
        knots
    end
    
    methods (Access = private)                          % update smoother knots and reestimate
        function smooth(app)
            rng('default')
            
            for k = 1:length(app.knots), app.knots{k} = [0 app.knots{k} 1]; end
            app.smoother.update_knots(app.knots);
            
            app.smoother.optimize()
        end
        
        function plot_smoothed(app)                     % update plotted smoothed measurements
            for idx = 1:numel(app.lastplots), delete(app.lastplots(idx)), end
            
            observed_state_index = find(strcmp(app.StatesDropDown.Value, app.state_names));
            
            if string(app.StatesDropDown.Value) ~= "All"
                n_cells = app.CellsSlider.Value;
                ax = nexttile(app.tl, 1);
                app.lastplots(1:n_cells, 1) = plot(ax, linspace(app.smoother.data.t(1), app.smoother.data.t(end), 201), ...
                    reshape(app.smoother.data.smoothed_fine(:, observed_state_index, 1:n_cells), 201, []), '--', 'LineWidth', 1.25);
                set(app.lastplots(1:n_cells, 1), {'color'}, num2cell(turbo(n_cells), 2));
            else
                n_cells = app.CellsSlider.Value;
                observed_indices = find(app.active);
                for idx = 1:length(observed_indices)
                    ax = nexttile(app.tl, idx);
                    app.lastplots(1:n_cells, idx) = plot(ax, linspace(app.smoother.data.t(1), app.smoother.data.t(end), 201), ...
                        reshape(app.smoother.data.smoothed_fine(:, idx, 1:n_cells), 201, []), '--', 'LineWidth', 1.25);
                    set(app.lastplots(1:n_cells, idx), {'color'}, num2cell(turbo(n_cells), 2));
                end
            end
        end
        
        function setup_plots(app)                       % create (tiled)layout with plotted measurements
            if string(app.StatesDropDown.Value) ~= "All"
                observed_state_index = find(strcmp(app.StatesDropDown.Value, app.state_names));
                delete(app.tl)
                app.tl = tiledlayout(app.RightPanel, 1, 1);
                
                ax = nexttile(app.tl, 1);
                hold(ax, 'on')
                h = plot(ax, app.smoother.data.t, ...
                         reshape(app.smoother.data.traces(:, observed_state_index, 1:app.CellsSlider.Value), ...
                                 length(app.smoother.data.t), []));
                set(h, {'color'}, num2cell(turbo(app.CellsSlider.Value), 2));
                title(ax, app.StatesDropDown.Value)
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            else
                nplots = length(app.smoother.data.observed);
                ncols = ceil(sqrt(nplots));
                nrows = ceil(nplots / ncols);
                delete(app.tl)
                app.tl = tiledlayout(app.RightPanel, nrows, ncols);
                
                for idx = 1:nplots
                    ax = nexttile(app.tl, idx);
                    hold(ax, 'on')
                    h = plot(ax, app.smoother.data.t, ...
                             reshape(app.smoother.data.traces(:, idx, 1:app.CellsSlider.Value), ...
                                     length(app.smoother.data.t), []));
                    set(h, {'color'}, num2cell(turbo(app.CellsSlider.Value), 2));
                    title(ax, app.state_names{idx})
                    xlabel(ax, 'time (min)')
                    ylabel(ax, 'value (AU)')
                end
            end
        end
        
        function update_knots(app)                      % update knots in slider and selector
            t = linspace(app.smoother.data.t(1), app.smoother.data.t(end), 201);
            
            if length(app.active) == 1
                app.KnotsSlider.Value = length(app.knots{app.active})-2;
                basis = app.smoother.B_fine{app.active};
                plot(app.KnotsSelectorUIAxes, t, basis, 'HitTest', 'off')
                if ~isempty(app.knots{app.active})
                    hold(app.KnotsSelectorUIAxes, 'on')
                    xline(app.KnotsSelectorUIAxes, app.knots{app.active} * range(app.smoother.data.t) + app.smoother.data.t(1), 'k--', 'HitTest', 'off')
                    hold(app.KnotsSelectorUIAxes, 'off')
                end
            else
                app.KnotsSlider.Value = length(app.knots{1})-2;
                basis = app.smoother.B_fine{1};
                plot(app.KnotsSelectorUIAxes, t, basis, 'HitTest', 'off')
                if ~isempty(app.knots{1})
                    hold(app.KnotsSelectorUIAxes, 'on')
                    xline(app.KnotsSelectorUIAxes, app.knots{1} * range(app.smoother.data.t) + app.smoother.data.t(1), 'k--', 'HitTest', 'off')
                    hold(app.KnotsSelectorUIAxes, 'off')
                end
            end
            
            xlim(app.KnotsSelectorUIAxes, [min(app.smoother.data.t) max(app.smoother.data.t)])
            ylim(app.KnotsSelectorUIAxes, [0 1])
            
            if app.LiveCheckBox.Value
                app.plot_smoothed()
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, smoother)
            app.smoother = smoother;
            
            app.state_names = convertStringsToChars(smoother.system.states(smoother.data.observed));
            if length(smoother.data.observed) == 1
                app.state_names = {app.state_names};
            end
            app.StatesDropDown.Items = ['All'; app.state_names];
            app.StatesDropDown.Value = 'All';
            
            app.N = smoother.data.N;
            app.L = length(smoother.data.observed);
            app.lastplots = gobjects(app.N, app.L);
            app.active = 1:app.L;
            
            app.knots = cell(1, app.L);
            for idx = 1:app.L
                app.knots{idx} = smoother.settings.knots{idx}(~ismember(smoother.settings.knots{idx}, [0 1]));
            end
            app.KnotsSlider.Value = length(app.knots{1});
            
            app.CellsSlider.Limits = [1 app.N];
            app.CellsSlider.Value = min(20, app.N);
            
            steps = [1 2 5 10 25 50 100 250 500 1000 2500 5000 10000];
            [~, minIdx] = min(abs(app.N/5 - steps));
            majorsize = steps(minIdx);
            minorsize = ceil(majorsize/5);
            app.CellsSlider.MajorTicksMode = 'manual';
            app.CellsSlider.MajorTicks = [1 majorsize : majorsize : app.N-mod(app.N,ceil(majorsize/2))-ceil(majorsize/2) app.N];
            app.CellsSlider.MinorTicksMode = 'manual';
            app.CellsSlider.MinorTicks = [1 minorsize : minorsize : app.N-minorsize];
            
            app.SmoothButton.Enable = false;
            
            app.smooth();
            app.setup_plots()
            app.update_knots()
        end

        % Value changed function: StatesDropDown
        function StateValueChanged(app, event)
            value = app.StatesDropDown.Value;
            if string(value) == "All"
                app.active = 1:app.L;
            else
                app.active = find(strcmp(value, app.state_names));
            end
            app.setup_plots()
            app.update_knots()
        end

        % Button pushed function: AutomaticButton
        function AutomaticButtonPushed(app, event)
            app.smoother.place_knots()
            for k = app.active
                app.knots{k} = app.smoother.settings.knots{k};
            end
            app.smooth();
            app.update_knots()
        end

        % Value changed function: KnotsSlider
        function KnotsSliderValueChanged(app, event)
            value = app.KnotsSlider.Value;
            event.Source.Value = round(value);
            
            for k = app.active
                app.knots{k} = linspace(0, 1, app.KnotsSlider.Value+2);
                app.knots{k} = app.knots{k}(2:end-1);
            end
            
            app.smooth();
            app.update_knots()
        end

        % Button down function: KnotsSelectorUIAxes
        function KnotsSelectorButtonDown(app, event)
            if length(app.active) > 1, k = 1; else, k = app.active; end
            
            x = app.KnotsSelectorUIAxes.CurrentPoint(1,1);
            knots_rescaled = app.knots{k} * range(app.smoother.data.t) + app.smoother.data.t(1);
            x_normalized = (x - app.smoother.data.t(1)) / range(app.smoother.data.t);
            
            if strcmp(app.UIFigure.SelectionType, 'alt')
                if length(app.knots{k}) > 1
                    distances = abs(x - knots_rescaled);
                    app.knots{k} = app.knots{k}(distances > min(distances));
                else
                    app.knots{k} = [];
                end
            else
                app.knots{k} = [app.knots{k} x_normalized];
            end
            
            app.knots{k} = sort(app.knots{k});
            if length(app.active) > 1
                for k = 2:length(app.active), app.knots{k} = app.knots{1}; end
            end
            
            app.smooth();
            app.update_knots()
        end

        % Value changed function: CellsSlider
        function CellsSliderValueChanged(app, event)
            event.Source.Value = round(app.CellsSlider.Value);
            app.setup_plots()
            app.plot_smoothed()
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.smooth()
            app.plot_smoothed()
        end

        % Value changed function: LiveCheckBox
        function LiveValueChanged(app, event)
            app.SmoothButton.Enable = ~app.LiveCheckBox.Value;
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.FinishButton.UserData = 1;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KnotsSelectorUIAxes = uiaxes(app.LeftPanel);
            title(app.KnotsSelectorUIAxes, 'Manual knot selector')
            xlabel(app.KnotsSelectorUIAxes, 'time')
            app.KnotsSelectorUIAxes.ButtonDownFcn = createCallbackFcn(app, @KnotsSelectorButtonDown, true);
            app.SmoothButton = uibutton(app.LeftPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.LiveCheckBox = uicheckbox(app.LeftPanel);
            app.LiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveValueChanged, true);
            app.LiveCheckBox.Text = 'Live';
            app.LiveCheckBox.Value = true;
            app.OverridewithequidistantknotsSliderLabel = uilabel(app.LeftPanel);
            app.OverridewithequidistantknotsSliderLabel.Text = {'Override with'; 'equidistant knots'};
            app.KnotsSlider = uislider(app.LeftPanel);
            app.KnotsSlider.Limits = [0 40];
            app.KnotsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.KnotsSlider.MajorTickLabels = {'0', ' ', '10', ' ', '20', ' ', '30', ' ', '40'};
            app.KnotsSlider.ValueChangedFcn = createCallbackFcn(app, @KnotsSliderValueChanged, true);
            app.KnotsSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.KnotsSlider.Value = 20;
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CellstodisplaySliderLabel = uilabel(app.LeftPanel);
            app.CellstodisplaySliderLabel.Text = {'#Cells to'; 'display'};
            app.CellsSlider = uislider(app.LeftPanel);
            app.CellsSlider.ValueChangedFcn = createCallbackFcn(app, @CellsSliderValueChanged, true);
            app.StatesDropDownLabel = uilabel(app.LeftPanel);
            app.StatesDropDownLabel.Text = 'State(s)';
            app.StatesDropDown = uidropdown(app.LeftPanel);
            app.StatesDropDown.Items = {'All'};
            app.StatesDropDown.ValueChangedFcn = createCallbackFcn(app, @StateValueChanged, true);
            app.StatesDropDown.Value = 'All';
            app.AutomaticButton = uibutton(app.LeftPanel, 'push');
            app.AutomaticButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticButtonPushed, true);
            app.AutomaticButton.Text = {'Override with automatic'; 'knot placement'};
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smoothing_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end